classdef AppMaxArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        maxarm                  matlab.ui.Figure
        movRobot                matlab.ui.control.UIAxes
        YminusButton            matlab.ui.control.Button
        editTable               matlab.ui.control.Table
        AgregardatosButton      matlab.ui.control.Button
        editTableText           matlab.ui.control.Label
        CapturardatosButton     matlab.ui.control.Button
        fixedTable              matlab.ui.control.Table
        ResetButton             matlab.ui.control.Button
        BorrardatoButton        matlab.ui.control.Button
        EnviardatosButton       matlab.ui.control.Button
        YplusButton             matlab.ui.control.Button
        XplusButton             matlab.ui.control.Button
        XminusButton            matlab.ui.control.Button
        ZplusButton             matlab.ui.control.Button
        ZminusButton            matlab.ui.control.Button
        GraficarButton          matlab.ui.control.Button
        MaxArmNoEditFieldLabel  matlab.ui.control.Label
        MaxArmNo                matlab.ui.control.NumericEditField
        ConectarButton          matlab.ui.control.Button
        Lamp                    matlab.ui.control.Lamp
        DesconectarButton       matlab.ui.control.Button
        XLabel                  matlab.ui.control.Label
        Xvalue                  matlab.ui.control.Label
        YLabel                  matlab.ui.control.Label
        Yvalue                  matlab.ui.control.Label
        ZLabel                  matlab.ui.control.Label
        Zvalue                  matlab.ui.control.Label
        TrajectoryAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot % Description
    end
    properties (Access = private)
        x_pos = -1; % Description
    end
    properties (Access = private)
        y_pos = -161; % Description
    end
    properties (Access = private)
        z_pos = 209; % Description
    end
    properties (Access = private)
        toolAng = 0; % Description
    end
    properties (Access = private)
        suction = false; % Description
    end
     properties (Access = private)
        trajectory_x = [];
        trajectory_y = [];
        trajectory_z = [];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Xvalue.Text = num2str(app.x_pos); 
            app.Yvalue.Text = num2str(app.y_pos);
            app.Zvalue.Text = num2str(app.z_pos);
            app.Lamp.Color = 'red';
            app.editTable.Data = {}; 
            app.editTable.ColumnName = {'X', 'Y', 'Z', 'Herramienta', 'Succión'};
            app.editTable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'logical'};
            app.editTable.ColumnEditable = true(1,5);
        end

        % Callback function: editTable, editTable
        function editTableCellEdit(app, event)
            
        end

        % Button pushed function: YminusButton
        function YminusButtonPushed(app, event)
                app.y_pos = app.y_pos - 1; 
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end
        end

        % Button pushed function: AgregardatosButton
        function AgregardatosButtonPushed(app, event)
             data = app.editTable.Data;
            
             if ~isempty(data)
                lastRow = data(end, :); % Obtener la última fila
                isEmptyRow = any(cellfun(@isempty, lastRow(1:4))); 
                
                if isEmptyRow
                    uialert(app.maxarm, 'Complete todos los campos de la fila actual antes de agregar una nueva.', 'Advertencia');
                    return; % Evitar que se agregue una nueva fila
                end
            end
        
            if isempty(data)
                data = {[], [], [], [], false};
            else
                data(end+1, :) = {[], [], [], [], false}; 
            end
   
            app.editTable.Data = data;  
        end

        % Button pushed function: CapturardatosButton
        function CapturardatosButtonPushed(app, event)

            data = app.editTable.Data;
            app.fixedTable.Data = data;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                app.editTable.Data = {};
                app.fixedTable.Data = {};
                cla(app.TrajectoryAxes);
                cla(app.movRobot);
                app.z_pos = 209; 
                app.y_pos = -161; 
                app.x_pos = -1; 
                app.suction = false;
                app.toolAng = 0;
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end
        end

        % Button pushed function: BorrardatoButton
        function BorrardatoButtonPushed(app, event)
            data = app.editTable.Data;
            if ~isempty(data)
                data(end, :) = [];
                app.editTable.Data = data;
            else
                uialert(app.maxarm, 'No hay datos para borrar', 'Advertencia');
            end
        
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)

            app.robot = maxarm_connect(app.MaxArmNo.Value)
            if ~isempty(app.robot);
                app.Lamp.Color = 'green';
            else
                app.Lamp.Color = 'red';
                uialert(app.maxarm, 'No se pudo conectar con el robot', 'Advertencia');
            end
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
           try
                maxarm_disconnect(app.robot)
                app.Lamp.Color = 'red';
           catch
               uialert(app.maxarm, 'No se pudo desconectar el robot o no existe ningun robot conectado', 'Advertencia');
           end
        end

        % Button pushed function: EnviardatosButton
        function EnviardatosButtonPushed(app, event)
            num_filas = size(app.fixedTable.Data, 1);
            
            for i = 1:num_filas
                app.x_pos = app.fixedTable.Data{i, 1}; 
                app.y_pos = app.fixedTable.Data{i, 2};  
                app.z_pos = app.fixedTable.Data{i, 3};  
                app.toolAng = app.fixedTable.Data{i, 4}; 
                app.suction = app.fixedTable.Data{i, 5}; 
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end
            end
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
             % Dimensiones del robot
                L0 = 84.4;
                L1 = 8.14;
                d1 = L0;
                a1 = L1;
                alpha1 = pi() / 2; % Cambio en el ángulo alpha para invertir el eje x
                offset1 = -pi()/2;
            
                d2 = 0;
                a2 = 128.4;
                alpha2 = pi();
                offset2 = pi() / 2;
            
                d3 = 0;
                a3 = 138;
                alpha3 = pi();
                offset3 = pi() / 2;
            
                d4 = 0;
                a4 = 16.8;
                alpha4 = -pi() / 2; % Cambio en el ángulo alpha para invertir el eje x
                offset4 = 0;
            
                d5 = 0;
                a5 = 0;
                alpha5 = pi(); % Cambio en el ángulo alpha para invertir el eje x
                offset5 = 0;
            
                % Crear el robot usando las dimensiones especificadas
                J1 = Revolute('d', d1, 'a', a1, 'alpha', alpha1, 'offset', offset1);
                J2 = Revolute('d', d2, 'a', a2, 'alpha', alpha2, 'offset', offset2);
                J3 = Revolute('d', d3, 'a', a3, 'alpha', alpha3, 'offset', offset3);
                J4 = Revolute('d', d4, 'a', a4, 'alpha', alpha4, 'offset', offset4);
                J5 = Revolute('d', d5, 'a', a5, 'alpha', alpha5, 'offset', offset5);
            
                MaxArm = SerialLink([J1, J2, J3, J4, J5], 'name', 'MAXARM');
            
                % Obtener la cantidad de filas
                num_filas = size(app.fixedTable.Data, 1);
            
                % Inicializar las coordenadas para la gráfica de trayectoria
                if isempty(app.trajectory_x)
                    app.trajectory_x = [];
                    app.trajectory_y = [];
                    app.trajectory_z = [];
                end
            
                % Iterar sobre cada fila de datos
                for i = 1:num_filas
                    % Extraer las posiciones (x, y, z) y el ángulo de la herramienta
                    app.x_pos = app.fixedTable.Data{i, 1};
                    app.y_pos = app.fixedTable.Data{i, 2};
                    app.z_pos = app.fixedTable.Data{i, 3};
                    app.toolAng = app.fixedTable.Data{i, 4};
                    app.suction = app.fixedTable.Data{i, 5};
            
                    % Calcular la cinemática inversa (ejemplo de función fabricante_ikine)
                    q = [fabricante_ikine([app.x_pos, app.y_pos, app.z_pos]), app.toolAng];
                    disp(rad2deg(q))
            
                    % Validar los ángulos obtenidos
                    if (q(1) < 0 || q(1) > 240)
                        error('Ángulo de la articulación 1 fuera de rango.');
                    elseif (q(2) < -30 || q(2) > 210)
                        error('Ángulo de la articulación 2 fuera de rango.');
                    elseif (q(3) < -120 || q(3) > 120)
                        error('Ángulo de la articulación 3 fuera de rango.');
                    else
                        % Convertir a ángulos del motor (motores a articulaciones)
                        q = deg2rad(q);
                        q0 = [motors2joints([q(1), q(2), q(3)]), q(4)];
                        disp(q0)
            
                        % Graficar en el UIAxes de la interfaz
                        cla(app.movRobot);
                        f = figure('Visible', 'off');  % Crear la figura en segundo plano
            
                        % Graficar el robot en esa figura temporal
                        MaxArm.plot(q0,'zoom', 3);
                        set(gca, 'XDir', 'reverse');
            
                        % Obtener el handle del contenido de la figura generada
                        ax = gca;  % Obtener el current axes de la figura temporal
            
                        % Copiar el contenido al UIAxes en la interfaz de App Designer
                        copyobj(allchild(ax), app.movRobot);  % Copiar todos los objetos hijos al UIAxes
            
                        % Configurar los ejes en app.movRobot
                        app.movRobot.XLim = ax.XLim;
                        app.movRobot.YLim = ax.YLim;
                        app.movRobot.ZLim = ax.ZLim;
                        app.movRobot.View = ax.View;  % Configurar la vista 3D
            
                        % Cerrar la figura temporal
                        close(f);
            
                        % Actualizar las coordenadas de la trayectoria
                        app.trajectory_x = [app.trajectory_x, app.x_pos];
                        app.trajectory_y = [app.trajectory_y, app.y_pos];
                        app.trajectory_z = [app.trajectory_z, app.z_pos];
            
                        % Graficar la trayectoria en el otro UIAxes
                        cla(app.TrajectoryAxes);
                        plot3(app.TrajectoryAxes, app.trajectory_x, app.trajectory_y, app.trajectory_z, '-o');
                        xlabel(app.TrajectoryAxes, 'X');
                        ylabel(app.TrajectoryAxes, 'Y');
                        zlabel(app.TrajectoryAxes, 'Z');
                        title(app.TrajectoryAxes, 'Trayectoria del Robot');
                        grid(app.TrajectoryAxes, 'on');
                        
                        pause(0.6); % Pausa entre movimientos
                    end
                end
        end

        % Button pushed function: XminusButton
        function XminusButtonPushed(app, event)

                app.x_pos = app.x_pos - 1;
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end
        end

        % Button pushed function: XplusButton
        function XplusButtonPushed(app, event)
                app.x_pos = app.x_pos + 1; 
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end

                
        end

        % Button pushed function: YplusButton
        function YplusButtonPushed(app, event)
                app.y_pos = app.y_pos + 1; 
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end
        end

        % Button pushed function: ZplusButton
        function ZplusButtonPushed(app, event)
                app.z_pos = app.z_pos + 1; 
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end
        end

        % Button pushed function: ZminusButton
        function ZminusButtonPushed(app, event)
                app.z_pos = app.z_pos - 1;
                app.Xvalue.Text = num2str(app.x_pos); 
                app.Yvalue.Text = num2str(app.y_pos);
                app.Zvalue.Text = num2str(app.z_pos);
                q = [fabricante_ikine([app.x_pos,app.y_pos,app.z_pos]),app.toolAng];
                if (q(1)< 0 || q(1)> 240)
                    error('Ángulo de la articulación 1 fuera de rango.');
                elseif (q(2) < -30 ||q(2) > 210)
                    error('Ángulo de la articulación 2 fuera de rango.');
                elseif (q(3) < -120 || q(3) > 120)
                    error('Ángulo de la articulación 3 fuera de rango.');
                else 
                    maxarm_send(app.robot, q, app.suction);
                    pause(0.6);
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.maxarm = uifigure('Visible', 'off');
            app.maxarm.Name = 'maxarm';
            app.movRobot = uiaxes(app.maxarm);
            title(app.movRobot, 'Movimiento del robot')
            xlabel(app.movRobot, 'X')
            ylabel(app.movRobot, 'Y')
            app.YminusButton = uibutton(app.maxarm, 'push');
            app.YminusButton.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.YminusButton.Text = '-Y';
            app.editTable = uitable(app.maxarm);
            app.editTable.ColumnName = {'X'; 'Y'; 'Z'; 'Herramienta'; 'Succión'};
            app.editTable.RowName = {};
            app.editTable.ColumnEditable = [true true true true false];
            app.editTable.CellEditCallback = createCallbackFcn(app, @editTableCellEdit, true);
            app.editTable.CellSelectionCallback = createCallbackFcn(app, @editTableCellEdit, true);
            app.AgregardatosButton = uibutton(app.maxarm, 'push');
            app.AgregardatosButton.ButtonPushedFcn = createCallbackFcn(app, @AgregardatosButtonPushed, true);
            app.AgregardatosButton.Text = {'Agregar datos'; ''};
            app.editTableText = uilabel(app.maxarm);
            app.editTableText.Text = '';
            app.CapturardatosButton = uibutton(app.maxarm, 'push');
            app.CapturardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CapturardatosButtonPushed, true);
            app.CapturardatosButton.Text = {'Capturar datos'; ''};
            app.fixedTable = uitable(app.maxarm);
            app.fixedTable.ColumnName = {'X'; 'Y'; 'Z'; 'Herramienta'; 'Succión'};
            app.fixedTable.RowName = {};
            app.ResetButton = uibutton(app.maxarm, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.BorrardatoButton = uibutton(app.maxarm, 'push');
            app.BorrardatoButton.ButtonPushedFcn = createCallbackFcn(app, @BorrardatoButtonPushed, true);
            app.BorrardatoButton.Text = 'Borrar dato';
            app.EnviardatosButton = uibutton(app.maxarm, 'push');
            app.EnviardatosButton.ButtonPushedFcn = createCallbackFcn(app, @EnviardatosButtonPushed, true);
            app.EnviardatosButton.Text = 'Enviar datos';
            app.YplusButton = uibutton(app.maxarm, 'push');
            app.YplusButton.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.YplusButton.Text = '+Y';
            app.XplusButton = uibutton(app.maxarm, 'push');
            app.XplusButton.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.XplusButton.Text = '+X';
            app.XminusButton = uibutton(app.maxarm, 'push');
            app.XminusButton.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.XminusButton.Text = '-X';
            app.ZplusButton = uibutton(app.maxarm, 'push');
            app.ZplusButton.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.ZplusButton.Text = '+Z';
            app.ZminusButton = uibutton(app.maxarm, 'push');
            app.ZminusButton.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.ZminusButton.Text = '-Z';
            app.GraficarButton = uibutton(app.maxarm, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.MaxArmNoEditFieldLabel = uilabel(app.maxarm);
            app.MaxArmNoEditFieldLabel.Text = 'MaxArm No.';
            app.MaxArmNo = uieditfield(app.maxarm, 'numeric');
            app.ConectarButton = uibutton(app.maxarm, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.Lamp = uilamp(app.maxarm);
            app.DesconectarButton = uibutton(app.maxarm, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.XLabel = uilabel(app.maxarm);
            app.XLabel.Text = 'X:';
            app.Xvalue = uilabel(app.maxarm);
            app.Xvalue.Tag = 'Xvalue';
            app.YLabel = uilabel(app.maxarm);
            app.YLabel.Text = 'Y:';
            app.Yvalue = uilabel(app.maxarm);
            app.ZLabel = uilabel(app.maxarm);
            app.ZLabel.Text = 'Z:';
            app.Zvalue = uilabel(app.maxarm);
            app.TrajectoryAxes = uiaxes(app.maxarm);
            title(app.TrajectoryAxes, 'Movimiento del robot')
            xlabel(app.TrajectoryAxes, 'X')
            ylabel(app.TrajectoryAxes, 'Y')
            % Show the figure after all components are created
            app.maxarm.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppMaxArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.maxarm)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.maxarm)
        end
    end
end